classdef SignalProcessor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        PlayStopButton           matlab.ui.control.StateButton
        BufferSizeDropDown       matlab.ui.control.DropDown
        BufferSizeDropDownLabel  matlab.ui.control.Label
        SignalProcessorLabel     matlab.ui.control.Label
        ImportFileButton         matlab.ui.control.Button
        frequencySpectrum        matlab.ui.control.UIAxes
        waveform                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        fullPath = "";
        bufferSize = 2048; % Sets the number of samples to be transformed via discrete fourier transform per iteration. A higher number means the transform will be taken from a larger time period so will be a more averaged representation of the sample, however a lower buffer size will give a more precise representation of the frequencies present at a given point in time of a signal which changes often and quickly
        player; % initialises audio player object


        % the buffer should be at least twice or three times as large 
        % as the lowest frequency to be detected as this will give better
        % results for detecting said frequency using the fft function.
        % As the lowest frequency humans can hear is 20Hz, this value
        % will be used in the case of processing audio files.
        % 20*3 = 60, therefore 64 is the minimum buffer size possible in
        % this application.


        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % for some reason anything below 2048 does not extend down to 20Hz
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportFileButton
        function ImportFileButtonPushed(app, event)
            [file,path] = uigetfile('*.wav','Select a .wav file'); % Prompts user to select a file for input and stores the filename and path as variables
            app.fullPath = append(path, file); % Converts the filename and path to a single string to be read by the audioread function
            % add file validation routine
            waveformY = audioread(app.fullPath); % Stores amplitude data in an array
            colororder(app.waveform, {'c', 'm'}); 
            app.waveform.Color = 'k';
            plot(app.waveform, waveformY); % Plots the data on the axes to display the waveform of the file
            app.waveform.Title.String = file;

            [samples, sampleRate] = audioread(app.fullPath); % Obtains the samples and sample rate in Hz from the audio file imported
            app.player = audioplayer(samples, sampleRate);
            play(app.player);

            app.frequencySpectrum.XGrid = "on";
            
            disp("duration: ")
            disp(app.bufferSize/sampleRate*length(1:app.bufferSize:(length(samples)-app.bufferSize)));


            % this is too inefficient to play in real time, make a matrix
            % of Y through time and plot. Call play after construction of
            % matrix.
            
            for i = 1:app.bufferSize:(length(samples)-app.bufferSize) % Iterates through the audio file for the number of divisions possible at the given bufferSize until the end of the file is reached
                
                sampleTake = samples(i:(i+app.bufferSize-1)); % Takes bufferSize number of samples from the audio file at point i in the file. This splits the file into many parts to be analysed individually
                amplitudesY = fft(sampleTake, app.bufferSize); % Creates an array of amplitudes corrosponding to the discrete fourier transform of the imported file. This gives a spectrum of the frequencies present in the signal in the frequency domain with the Y axis corrosponding to their relative (convert to absolute in dB?) intensities
                %amplitudesY = amplitudesY-min(amplitudesY); % adjusts for
                %DC error (incorrectly), use RMS?
                frequenciesX = sampleRate/2*linspace(0,1,app.bufferSize/2+1); % Creates an array of frequencies for the x axis
                loglog(app.frequencySpectrum, frequenciesX, abs(amplitudesY(1:app.bufferSize/2+1))); % A logorithmic scale is used because when octaves linearly increase, the frequency increases at f^n, thus an exponential frequency domain is a more representative method for plotting audio frequencies. The unit for amplitude, decibells, is also non linear so the y axis must also be logorithmic to reflect this.
                ylim(app.frequencySpectrum, [0 (app.bufferSize/2)]); % As buffer size increases, the fft function takes more samples and the amplitude increases as a result. To maintain the scale at different buffer sizes, the y axis must have an adaptive scale. Modelling a sample as the average of the maximum and minimum amplitude of a sine wave gives 0.5 and then multiplying this by the number of samples in the buffer size will provide an adequate estimate for the maximum scale.
                xlim(app.frequencySpectrum, [20 20000]); % Sets the x axis limits to that of the minimum/maximum human frequency detection range
                
                pause(app.bufferSize/sampleRate); % In order to plot in real time, using the formula f = 1/t, the delay time can be calculated by 1/f = t. This must then be multiplied by the buffer size as the number of samples taken alters the duration of each plot.
            end
            
        end

        % Value changed function: BufferSizeDropDown
        function BufferSizeDropDownValueChanged(app, event)
            value = str2double(app.BufferSizeDropDown.Value);
            app.bufferSize = value;
        end

        % Value changed function: PlayStopButton
        function PlayStopButtonValueChanged(app, event)
            value = app.PlayStopButton.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.waveform = uiaxes(app.GridLayout);
            title(app.waveform, '[ Import a file ]')
            xlabel(app.waveform, 'Time /s')
            ylabel(app.waveform, 'Amplitude /dB')
            zlabel(app.waveform, 'Z')
            app.frequencySpectrum = uiaxes(app.GridLayout);
            title(app.frequencySpectrum, 'Frequency Spectrum')
            xlabel(app.frequencySpectrum, 'Frequency')
            ylabel(app.frequencySpectrum, 'Amplitude')
            zlabel(app.frequencySpectrum, 'Time')
            app.ImportFileButton = uibutton(app.GridLayout, 'push');
            app.ImportFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportFileButtonPushed, true);
            app.ImportFileButton.Text = 'Import File';
            app.SignalProcessorLabel = uilabel(app.GridLayout);
            app.SignalProcessorLabel.Text = 'Signal Processor';
            app.BufferSizeDropDownLabel = uilabel(app.GridLayout);
            app.BufferSizeDropDownLabel.Text = 'Buffer Size';
            app.BufferSizeDropDown = uidropdown(app.GridLayout);
            app.BufferSizeDropDown.Items = {'256', '512', '1024', '2048'};
            app.BufferSizeDropDown.ValueChangedFcn = createCallbackFcn(app, @BufferSizeDropDownValueChanged, true);
            app.BufferSizeDropDown.Value = '2048';
            app.PlayStopButton = uibutton(app.GridLayout, 'state');
            app.PlayStopButton.ValueChangedFcn = createCallbackFcn(app, @PlayStopButtonValueChanged, true);
            app.PlayStopButton.Text = 'Play/Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SignalProcessor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end